classdef gui1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Menu                         matlab.ui.container.Menu
        Menu2                        matlab.ui.container.Menu
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        StatusNoneLabel              matlab.ui.control.Label
        RunButton                    matlab.ui.control.StateButton
        TabGroup                     matlab.ui.container.TabGroup
        GeneralTab                   matlab.ui.container.Tab
        ModesListBox                 matlab.ui.control.ListBox
        ModesListBoxLabel            matlab.ui.control.Label
        MaxIterationsEditField       matlab.ui.control.NumericEditField
        MaxIterationsEditFieldLabel  matlab.ui.control.Label
        PathDropDown                 matlab.ui.control.DropDown
        PathDropDownLabel            matlab.ui.control.Label
        TerrainDropDown              matlab.ui.control.DropDown
        TerrainDropDownLabel         matlab.ui.control.Label
        PathTab_2                    matlab.ui.container.Tab
        AmplitudeEditField           matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel      matlab.ui.control.Label
        EndYEditField                matlab.ui.control.NumericEditField
        EndYEditFieldLabel           matlab.ui.control.Label
        StartYEditField              matlab.ui.control.NumericEditField
        StartYEditFieldLabel         matlab.ui.control.Label
        EndXEditField                matlab.ui.control.NumericEditField
        EndXEditFieldLabel           matlab.ui.control.Label
        StartXEditField              matlab.ui.control.NumericEditField
        StartXEditFieldLabel         matlab.ui.control.Label
        TerrainTab                   matlab.ui.container.Tab
        AmplitudeEditField_2         matlab.ui.control.NumericEditField
        AmplitudeEditField_2Label    matlab.ui.control.Label
        FilterSizeSpinner            matlab.ui.control.Spinner
        FilterSizeSpinnerLabel       matlab.ui.control.Label
        FrequencyEditField           matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel      matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        TabGroup2                    matlab.ui.container.TabGroup
        Tab                          matlab.ui.container.Tab
        VisualTab                    matlab.ui.container.Tab
        EqualAxisCheckBox            matlab.ui.control.CheckBox
        ShowColorBarCheckBox         matlab.ui.control.CheckBox
        ShowAxisDropDown             matlab.ui.control.DropDown
        ShowAxisDropDownLabel        matlab.ui.control.Label
        EndStateEditField            matlab.ui.control.NumericEditField
        EndStateEditFieldLabel       matlab.ui.control.Label
        StartStateEditField          matlab.ui.control.NumericEditField
        StartStateEditFieldLabel     matlab.ui.control.Label
        RateEditField                matlab.ui.control.NumericEditField
        RateEditFieldLabel           matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        env % Environment variable
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: RunButton
        function RunButtonValueChanged(app, event)
            value = app.RunButton.Value;
            app.StatusNoneLabel.Text = 'Status: Running...';
            
            pathDirectory = 'input_path'; 
            terrainDirectory = 'input_terrain';
            outputEnvDirectory = 'output';
            pathName = '';
            terrainName = '';
            
            %% ===== TEST PARAMS ======
            loadFromSaved = false;
            showVisual = true;
            
            %% bot settings
            %botTypes = {app.ModeDropDown.Value};
            botTypes = app.ModesListBox.Value;
            botStartX = app.StartXEditField.Value;
            maxIterations = app.MaxIterationsEditField.Value;
            
            %% path settings
            pathType = app.PathDropDown.Value;
            pathStartX = app.StartXEditField.Value;
            pathStartY = app.StartYEditField.Value;
            pathEndX = app.EndXEditField.Value;
            pathEndY = app.EndYEditField.Value;
            pathAmplitude = app.AmplitudeEditField.Value;
            
            %% terrain settings
            terrainType = app.TerrainDropDown.Value;
            genTerrainFromPath = true;
            % custom, ignore if gen from path
            width = pathEndX+50;
            height = pathEndY+200;
            cellsize = 5;
            % sin
            terrainAmplitude = app.AmplitudeEditField_2.Value;
            terrainFrequency = app.FrequencyEditField.Value;
            % random
            randFilterSize = app.FilterSizeSpinner.Value;
            elevationChangeRange = 10;
            %terrainName = 'customTerrainName';
            
            %% visualize settings
            rate = app.RateEditField.Value;
            startState = app.StartStateEditField.Value;
            stopState = -1;
            showAllMarkers = true;
            showAxis = app.ShowAxisDropDown.Value;
            showColorBar = 0;
            equalAxis = false;
            
            pathRequiredArgs = {pathType, pathStartX, pathStartY, pathEndX, pathEndY};
            pathExtraArgs = {pathAmplitude};
            pathArgs = [pathRequiredArgs pathExtraArgs];
            terrainRequiredArgs = {genTerrainFromPath, pathName, terrainType};
            terrainExtraArgs = {width, height, cellsize, terrainAmplitude, terrainFrequency, randFilterSize, elevationChangeRange};
            terrainArgs = [terrainRequiredArgs terrainExtraArgs];
            
            %% run simulation---
            env = Environment(outputEnvDirectory, false, pathDirectory, terrainDirectory, ...
                    pathName, terrainName, pathArgs, terrainArgs);
        
            env = env.analyze(botTypes, botStartX, maxIterations);
            env.visualize(botTypes{1}, ...
                    rate, startState, stopState, showAllMarkers, showAxis, showColorBar, equalAxis);
        
            app.StatusNoneLabel.Text = 'Status: Done!';
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {228, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.UIFigure);
            app.Menu2.Text = 'Menu2';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.TerrainDropDownLabel = uilabel(app.GeneralTab);
            app.TerrainDropDownLabel.Text = 'Terrain';
            app.TerrainDropDown = uidropdown(app.GeneralTab);
            app.TerrainDropDown.Items = {'flat', 'ramp', 'sin', 'random', 'leftright', 'perlin'};
            app.TerrainDropDown.Value = 'flat';
            app.PathDropDownLabel = uilabel(app.GeneralTab);
            app.PathDropDownLabel.Text = 'Path';
            app.PathDropDown = uidropdown(app.GeneralTab);
            app.PathDropDown.Items = {'straight', 'line', 'halfcirc', 'sin'};
            app.PathDropDown.Value = 'straight';
            app.MaxIterationsEditFieldLabel = uilabel(app.GeneralTab);
            app.MaxIterationsEditFieldLabel.Text = 'Max Iterations';
            app.MaxIterationsEditField = uieditfield(app.GeneralTab, 'numeric');
            app.MaxIterationsEditField.Value = 10;
            app.ModesListBoxLabel = uilabel(app.GeneralTab);
            app.ModesListBoxLabel.Text = 'Mode(s)';
            app.ModesListBox = uilistbox(app.GeneralTab);
            app.ModesListBox.Items = {'pull', 'roll', 'walk'};
            app.ModesListBox.Multiselect = 'on';
            app.ModesListBox.Value = {'pull'};
            app.PathTab_2 = uitab(app.TabGroup);
            app.PathTab_2.Title = 'Path';
            app.StartXEditFieldLabel = uilabel(app.PathTab_2);
            app.StartXEditFieldLabel.Text = 'Start X';
            app.StartXEditField = uieditfield(app.PathTab_2, 'numeric');
            app.StartXEditField.Value = 50;
            app.EndXEditFieldLabel = uilabel(app.PathTab_2);
            app.EndXEditFieldLabel.Text = 'End X';
            app.EndXEditField = uieditfield(app.PathTab_2, 'numeric');
            app.EndXEditField.Value = 650;
            app.StartYEditFieldLabel = uilabel(app.PathTab_2);
            app.StartYEditFieldLabel.Text = 'Start Y';
            app.StartYEditField = uieditfield(app.PathTab_2, 'numeric');
            app.StartYEditField.Value = 100;
            app.EndYEditFieldLabel = uilabel(app.PathTab_2);
            app.EndYEditFieldLabel.Text = 'End Y';
            app.EndYEditField = uieditfield(app.PathTab_2, 'numeric');
            app.EndYEditField.Value = 130;
            app.AmplitudeEditFieldLabel = uilabel(app.PathTab_2);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.PathTab_2, 'numeric');
            app.AmplitudeEditField.Value = 100;
            app.TerrainTab = uitab(app.TabGroup);
            app.TerrainTab.Title = 'Terrain';
            app.FrequencyEditFieldLabel = uilabel(app.TerrainTab);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.TerrainTab, 'numeric');
            app.FrequencyEditField.Value = 0.01;
            app.FilterSizeSpinnerLabel = uilabel(app.TerrainTab);
            app.FilterSizeSpinnerLabel.Text = 'Filter Size';
            app.FilterSizeSpinner = uispinner(app.TerrainTab);
            app.FilterSizeSpinner.Value = 10;
            app.AmplitudeEditField_2Label = uilabel(app.TerrainTab);
            app.AmplitudeEditField_2Label.Text = 'Amplitude';
            app.AmplitudeEditField_2 = uieditfield(app.TerrainTab, 'numeric');
            app.AmplitudeEditField_2.Value = 10;
            app.RunButton = uibutton(app.LeftPanel, 'state');
            app.RunButton.ValueChangedFcn = createCallbackFcn(app, @RunButtonValueChanged, true);
            app.RunButton.Text = 'Run';
            app.StatusNoneLabel = uilabel(app.LeftPanel);
            app.StatusNoneLabel.Text = 'Status: None';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Joint Changes')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.TabGroup2 = uitabgroup(app.RightPanel);
            app.Tab = uitab(app.TabGroup2);
            app.Tab.Title = 'Tab';
            app.VisualTab = uitab(app.TabGroup2);
            app.VisualTab.Title = 'Visual';
            app.RateEditFieldLabel = uilabel(app.VisualTab);
            app.RateEditFieldLabel.Text = 'Rate';
            app.RateEditField = uieditfield(app.VisualTab, 'numeric');
            app.RateEditField.Value = 0.01;
            app.StartStateEditFieldLabel = uilabel(app.VisualTab);
            app.StartStateEditFieldLabel.Text = 'Start State';
            app.StartStateEditField = uieditfield(app.VisualTab, 'numeric');
            app.StartStateEditField.Value = 1;
            app.EndStateEditFieldLabel = uilabel(app.VisualTab);
            app.EndStateEditFieldLabel.Text = 'End State';
            app.EndStateEditField = uieditfield(app.VisualTab, 'numeric');
            app.EndStateEditField.Value = -1;
            app.ShowAxisDropDownLabel = uilabel(app.VisualTab);
            app.ShowAxisDropDownLabel.Text = 'Show Axis';
            app.ShowAxisDropDown = uidropdown(app.VisualTab);
            app.ShowAxisDropDown.Items = {'', 'x', 'y', 'z'};
            app.ShowAxisDropDown.Value = '';
            app.ShowColorBarCheckBox = uicheckbox(app.VisualTab);
            app.ShowColorBarCheckBox.Text = 'Show Color Bar';
            app.EqualAxisCheckBox = uicheckbox(app.VisualTab);
            app.EqualAxisCheckBox.Text = 'Equal Axis';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end